classdef kadai1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        compute   matlab.ui.control.Button
        load      matlab.ui.control.Button
        image1    matlab.ui.control.UIAxes
        image2    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: load
        function loadPushed(app, event)
            filename = uigetfile({'*.png;*.jpeg','Image files (*.png,*.jpeg)'});
            % read image
            im=imread(filename);
            % % plot image
            imshow(im, "Parent", app.image1);
        end

        % Button pushed function: compute
        function computePushed(app, event)
            filename = uigetfile({'*.png;*.jpeg','Image files (*.png,*.jpeg)'});
            % read image
            im=imread(filename);
            % compute image
            imgray = rgb2gray(im);
            BW = imbinarize(imgray);
            % plot images
            imshow(BW*255, "Parent", app.image2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.image2 = uiaxes(app.UIFigure);
            app.image1 = uiaxes(app.UIFigure);
            app.load = uibutton(app.UIFigure, 'push');
            app.load.ButtonPushedFcn = createCallbackFcn(app, @loadPushed, true);
            app.load.Text = 'load';
            app.compute = uibutton(app.UIFigure, 'push');
            app.compute.ButtonPushedFcn = createCallbackFcn(app, @computePushed, true);
            app.compute.Text = 'compute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kadai1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end